classdef ContinuoDifInt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BackButton          matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        IntegralSimpleTab   matlab.ui.container.Tab
        b_Int_simp          matlab.ui.control.EditField
        a_Int_simp          matlab.ui.control.EditField
        Res_int_simp        matlab.ui.control.NumericEditField
        CalcularButton      matlab.ui.control.Button
        f_int_simp          matlab.ui.control.EditField
        PasoEditField       matlab.ui.control.NumericEditField
        PasoEditFieldLabel  matlab.ui.control.Label
        dxLabel             matlab.ui.control.Label
        MtodoDropDown       matlab.ui.control.DropDown
        intLabel_9          matlab.ui.control.Label
        MtodoDropDownLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
        IntegralDobleTab    matlab.ui.container.Tab
        R_int_doble         matlab.ui.control.NumericEditField
        CalcularButton_2    matlab.ui.control.Button
        Label               matlab.ui.control.Label
        f_int_doble         matlab.ui.control.EditField
        b_var1_int_doble    matlab.ui.control.EditField
        b_var2_int_doble    matlab.ui.control.EditField
        a_var1_int_doble    matlab.ui.control.EditField
        a_var2_int_doble    matlab.ui.control.EditField
        var2_int_doble      matlab.ui.control.EditField
        var1_int_doble      matlab.ui.control.EditField
        dLabel_5            matlab.ui.control.Label
        dLabel_4            matlab.ui.control.Label
        intLabel_6          matlab.ui.control.Label
        intLabel_5          matlab.ui.control.Label
        IntegralTripleTab   matlab.ui.container.Tab
        CalcularButton_3    matlab.ui.control.Button
        Res_int_triple      matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        f_int_triple        matlab.ui.control.EditField
        b_var1_int_triple   matlab.ui.control.EditField
        b_var2_int_triple   matlab.ui.control.EditField
        b_var3_int_triple   matlab.ui.control.EditField
        a_var1_int_triple   matlab.ui.control.EditField
        a_var2_int_triple   matlab.ui.control.EditField
        a_var3_int_triple   matlab.ui.control.EditField
        var3_int_triple     matlab.ui.control.EditField
        var2_int_triple     matlab.ui.control.EditField
        var1_int_triple     matlab.ui.control.EditField
        dLabel_3            matlab.ui.control.Label
        dLabel_2            matlab.ui.control.Label
        dLabel              matlab.ui.control.Label
        intLabel_3          matlab.ui.control.Label
        intLabel_2          matlab.ui.control.Label
        intLabel            matlab.ui.control.Label
    end

    
    methods (Access = private)
        % Trapecio
        function I=trapecio2(~,f,a,b,h)
            I=0;
            n=ceil((b-a)/h);
            for i=1:n-1
                I=I+f(a+i*h);
            end
            I=h*I;
            I=I+h*(f(a)+f(b))/2;
        end

        % Simpson
        function I=Simpson(~,f,a,b,h)
            n=ceil((b-a)/h);
            u1=0;
            u2=0;
            for i=1:n-1
                if rem(i,2)==0
                    u1=u1+f(a+i*h);
                else
                    u2=u2+f(a+i*h);
                end
            end
            I=(h/3)*(f(a)+f(b)+2*u1+4*u2);
        end

        % Gauss Legendre
        function I=GaussLegendre(~,f,a,b)
            x=[0.9602898565 -0.9602898565 0.7966664774 -0.7966664774 0.5255324099 -0.5255324099  0.1834346425 -0.1834346425];
            w=[0.1012285363 0.1012285363 0.2223810345 0.2223810345  0.3137066459  0.3137066459  0.3626837834  0.3626837834];
            I=0;
            t1=(a+b)/2;
            t2=(b-a)/2;
            for k=1:8
                I=I+w(k)*f(t1+t2*x(k));
            end
            I=t2*I;
        end


        % Integral Doble
        function I=IntegralDobleSym(~,f,a,b,g1,g2,var)
            Ix=GaussLegendreSym(f,g1,g2,var(2));
            Iy=GaussLegendreSym(Ix,a,b,var(1));
            I=double(Iy);
            function I=GaussLegendreSym(f,a,b,var)
                x=[0.9602898565 -0.9602898565 0.7966664774 -0.7966664774 0.5255324099 -0.5255324099  0.1834346425 -0.1834346425];
                w=[0.1012285363 0.1012285363 0.2223810345 0.2223810345  0.3137066459  0.3137066459  0.3626837834  0.3626837834];
                I=0;
                t1=(a+b)/2;
                t2=(b-a)/2;
                for k=1:8
                    I=I+w(k)*subs(f,var,t1+t2*x(k));
                end
                I=t2*I;
            end
        end


        % Integral Triple
        function I=IntegralTripleSym2(~,f,a,b,g1,g2,h1,h2,var)
            Iz=GaussLegendreSym(f,h1,h2,var(3));
            Iy=GaussLegendreSym(Iz,g1,g2,var(2));
            Ix=GaussLegendreSym(Iy,a,b,var(1));
            I=double(Ix);
            function I=GaussLegendreSym(f,a,b,var)
                x=[0.9602898565 -0.9602898565 0.7966664774 -0.7966664774 0.5255324099 -0.5255324099  0.1834346425 -0.1834346425];
                w=[0.1012285363 0.1012285363 0.2223810345 0.2223810345  0.3137066459  0.3137066459  0.3626837834  0.3626837834];
                I=0;
                t1=(a+b)/2;
                t2=(b-a)/2;
                for k=1:8
                    I=I+w(k)*subs(f,var,t1+t2*x(k));
                end
                I=t2*I;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            IntegracionDiferenciacion
            delete(app)
        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value;
            if value=="Gauss Legendre"
                app.PasoEditField.Visible="off";
                app.PasoEditFieldLabel.Visible="off";
            elseif value=="Integral (Matlab)"
                app.PasoEditField.Visible="off";
                app.PasoEditFieldLabel.Visible="off";
            elseif value=="Trapecio"
                app.PasoEditField.Visible="on";
                app.PasoEditFieldLabel.Visible="on";
            elseif value=="Simpson"
                app.PasoEditField.Visible="on";
                app.PasoEditFieldLabel.Visible="on";
            end

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            f=app.f_int_simp.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            a=app.a_Int_simp.Value;
            a=str2num(a);
            b=app.b_Int_simp.Value;
            b=str2num(b);
            h=app.PasoEditField.Value;

            Metodo=app.MtodoDropDown.Value;
            if Metodo=="Gauss Legendre"
                I=app.GaussLegendre(f,a,b);
            elseif Metodo=="Integral (Matlab)"
                I=integral(f,a,b);
            elseif Metodo=="Trapecio"
                I=app.trapecio2(f,a,b,h);
            elseif Metodo=="Simpson"
                I=app.Simpson(f,a,b,h);
            end 
            
            format("long")
            app.Res_int_simp.Value=I;

            fplot(app.UIAxes,f,[a-1,b+1],'r','LineWidth',2)
            hold(app.UIAxes,"on")
            x=a:0.01:b;
            y=f(x);
            fill(app.UIAxes,[x,fliplr(x)],[y,zeros(size(y))],[rand,rand,rand])
            hold(app.UIAxes,"off")
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            syms x y
            f=app.f_int_doble.Value;
            f=str2sym(f);

            a=app.a_var2_int_doble.Value;
            a=str2sym(a);
            b=app.b_var2_int_doble.Value;
            b=str2sym(b);

            g1=app.a_var1_int_doble.Value;
            g1=str2sym(g1);
            g2=app.b_var1_int_doble.Value;
            g2=str2sym(g2);

            v1=app.var1_int_doble.Value;
            v1=str2sym(v1);

            v2=app.var2_int_doble.Value;
            v2=str2sym(v2);
    
            var=[v2 v1];

            I=app.IntegralDobleSym(f,a,b,g1,g2,var);
            format("long")
            app.R_int_doble.Value=I;
        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            syms x y z
            f=app.f_int_triple.Value;
            f=str2sym(f);

            a=app.a_var3_int_triple.Value;
            a=str2sym(a);
            b=app.b_var3_int_triple.Value;
            b=str2sym(b);

            g1=app.a_var2_int_triple.Value;
            g1=str2sym(g1);
            g2=app.b_var2_int_triple.Value;
            g2=str2sym(g2);

            h1=app.a_var1_int_triple.Value;
            h1=str2sym(h1);
            h2=app.b_var1_int_triple.Value;
            h2=str2sym(h2);

            v1=app.var1_int_triple.Value;
            v1=str2sym(v1);

            v2=app.var2_int_triple.Value;
            v2=str2sym(v2);

            v3=app.var3_int_triple.Value;
            v3=str2sym(v3);
    
            var=[v3 v2 v1];

            I=app.IntegralTripleSym2(f,a,b,g1,g2,h1,h2,var);
            format("long")
            app.Res_int_triple.Value=I;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntegralSimpleTab = uitab(app.TabGroup);
            app.IntegralSimpleTab.Title = 'Integral Simple';
            app.UIAxes = uiaxes(app.IntegralSimpleTab);
            title(app.UIAxes, 'Integral')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MtodoDropDownLabel = uilabel(app.IntegralSimpleTab);
            app.MtodoDropDownLabel.Text = 'Método';
            app.intLabel_9 = uilabel(app.IntegralSimpleTab);
            app.intLabel_9.Interpreter = 'latex';
            app.intLabel_9.Text = '\int';
            app.MtodoDropDown = uidropdown(app.IntegralSimpleTab);
            app.MtodoDropDown.Items = {'Trapecio', 'Simpson', 'Gauss Legendre', 'Integral (Matlab)'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'Trapecio';
            app.dxLabel = uilabel(app.IntegralSimpleTab);
            app.dxLabel.Interpreter = 'latex';
            app.dxLabel.Text = 'dx=';
            app.PasoEditFieldLabel = uilabel(app.IntegralSimpleTab);
            app.PasoEditFieldLabel.Text = 'Paso';
            app.PasoEditField = uieditfield(app.IntegralSimpleTab, 'numeric');
            app.f_int_simp = uieditfield(app.IntegralSimpleTab, 'text');
            app.CalcularButton = uibutton(app.IntegralSimpleTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Res_int_simp = uieditfield(app.IntegralSimpleTab, 'numeric');
            app.a_Int_simp = uieditfield(app.IntegralSimpleTab, 'text');
            app.b_Int_simp = uieditfield(app.IntegralSimpleTab, 'text');
            app.IntegralDobleTab = uitab(app.TabGroup);
            app.IntegralDobleTab.Title = 'Integral Doble';
            app.intLabel_5 = uilabel(app.IntegralDobleTab);
            app.intLabel_5.Interpreter = 'latex';
            app.intLabel_5.Text = '\int';
            app.intLabel_6 = uilabel(app.IntegralDobleTab);
            app.intLabel_6.Interpreter = 'latex';
            app.intLabel_6.Text = '\int';
            app.dLabel_4 = uilabel(app.IntegralDobleTab);
            app.dLabel_4.Interpreter = 'latex';
            app.dLabel_4.Text = 'd';
            app.dLabel_5 = uilabel(app.IntegralDobleTab);
            app.dLabel_5.Interpreter = 'latex';
            app.dLabel_5.Text = 'd';
            app.var1_int_doble = uieditfield(app.IntegralDobleTab, 'text');
            app.var2_int_doble = uieditfield(app.IntegralDobleTab, 'text');
            app.a_var2_int_doble = uieditfield(app.IntegralDobleTab, 'text');
            app.a_var1_int_doble = uieditfield(app.IntegralDobleTab, 'text');
            app.b_var2_int_doble = uieditfield(app.IntegralDobleTab, 'text');
            app.b_var1_int_doble = uieditfield(app.IntegralDobleTab, 'text');
            app.f_int_doble = uieditfield(app.IntegralDobleTab, 'text');
            app.Label = uilabel(app.IntegralDobleTab);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '=';
            app.CalcularButton_2 = uibutton(app.IntegralDobleTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.R_int_doble = uieditfield(app.IntegralDobleTab, 'numeric');
            app.IntegralTripleTab = uitab(app.TabGroup);
            app.IntegralTripleTab.Title = 'Integral Triple';
            app.intLabel = uilabel(app.IntegralTripleTab);
            app.intLabel.Interpreter = 'latex';
            app.intLabel.Text = '\int';
            app.intLabel_2 = uilabel(app.IntegralTripleTab);
            app.intLabel_2.Interpreter = 'latex';
            app.intLabel_2.Text = '\int';
            app.intLabel_3 = uilabel(app.IntegralTripleTab);
            app.intLabel_3.Interpreter = 'latex';
            app.intLabel_3.Text = '\int';
            app.dLabel = uilabel(app.IntegralTripleTab);
            app.dLabel.Interpreter = 'latex';
            app.dLabel.Text = 'd';
            app.dLabel_2 = uilabel(app.IntegralTripleTab);
            app.dLabel_2.Interpreter = 'latex';
            app.dLabel_2.Text = 'd';
            app.dLabel_3 = uilabel(app.IntegralTripleTab);
            app.dLabel_3.Interpreter = 'latex';
            app.dLabel_3.Text = 'd';
            app.var1_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.var2_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.var3_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.a_var3_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.a_var2_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.a_var1_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.b_var3_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.b_var2_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.b_var1_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.f_int_triple = uieditfield(app.IntegralTripleTab, 'text');
            app.Label_2 = uilabel(app.IntegralTripleTab);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '=';
            app.Res_int_triple = uieditfield(app.IntegralTripleTab, 'numeric');
            app.CalcularButton_3 = uibutton(app.IntegralTripleTab, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContinuoDifInt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end