classdef EDO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BackButton               matlab.ui.control.Button
        ytEditField              matlab.ui.control.EditField
        ytEditFieldLabel         matlab.ui.control.Label
        Y0EditField              matlab.ui.control.EditField
        Y0EditFieldLabel         matlab.ui.control.Label
        RunButton                matlab.ui.control.Button
        TamaoPasoEditField       matlab.ui.control.NumericEditField
        TamaoPasoEditFieldLabel  matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel          matlab.ui.control.Label
        aEditField               matlab.ui.control.NumericEditField
        aEditFieldLabel          matlab.ui.control.Label
        Solucion                 matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function A=Runge_Kutta_4(~,f,a,b,h,y0)
            % Este programa resuleve un sistema de ecuaciones diferenciales de primer
            % orden por el método de Runge Kutta orden cuatro
            % f=[f1,f2,...,fn]; es un vector renglon de las funciones del sistema
            % [a,b] es el intervalo en donde varía el tiempo
            % h es el tamaño de paso de la discretisación del intervalo [a b]
            % y0 es un vector renglon de condiciones inciales

            t=a:h:b; % Crea un vector renglon en el intervalo [a b]
            N=length(t); % Número de sub-intervalo en la discretisación del intervalo [a b]
            n=length(y0);
            Y=zeros(N,n);
            Y(1,:)=y0;

            for i=1:N-1
                G1=h*f(t(i),Y(i,:));
                G2=h*f(t(i)+h/2,Y(i,:)+G1/2);
                G3=h*f(t(i)+h/2,Y(i,:)+G2/2);
                G4=h*f(t(i)+h,Y(i,:)+G3);
                Y(i+1,:)=Y(i,:)+(G1+2*G2+2*G3+G4)/6;
            end
            A=[t',Y];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            y0 = num2str(app.Y0EditField.Value);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            h = app.TamaoPasoEditField.Value;
            f = str2func(['@(t,y)',app.ytEditField.Value]);

            A = app.Runge_Kutta_4(f,a,b,h,y0);
            app.Solucion.Data = A;

            plot(app.UIAxes,A(:,1),A(:,2))
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            index
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfico Runge Kutta')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            zlabel(app.UIAxes, 'Z')
            app.Solucion = uitable(app.UIFigure);
            app.Solucion.ColumnName = {'t'; 'y(t)'};
            app.Solucion.RowName = {};
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 2;
            app.TamaoPasoEditFieldLabel = uilabel(app.UIFigure);
            app.TamaoPasoEditFieldLabel.Text = 'Tamaño Paso';
            app.TamaoPasoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TamaoPasoEditField.Value = 0.1;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Y0EditFieldLabel = uilabel(app.UIFigure);
            app.Y0EditFieldLabel.Text = 'Y0';
            app.Y0EditField = uieditfield(app.UIFigure, 'text');
            app.Y0EditField.Value = '1';
            app.ytEditFieldLabel = uilabel(app.UIFigure);
            app.ytEditFieldLabel.Text = 'y(t)';
            app.ytEditField = uieditfield(app.UIFigure, 'text');
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end