classdef EcuacionesNoLineales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BackButton                     matlab.ui.control.StateButton
        TabGroup                       matlab.ui.container.TabGroup
        BiseccinTab                    matlab.ui.container.Tab
        GraficarButton                 matlab.ui.control.Button
        ResultadoEditField             matlab.ui.control.NumericEditField
        ResultadoEditFieldLabel        matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        ToleranciaEditField            matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel       matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        fxEditField                    matlab.ui.control.EditField
        fxEditFieldLabel               matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NewtonTab                      matlab.ui.container.Tab
        ErrorEditField                 matlab.ui.control.NumericEditField
        ErrorEditFieldLabel            matlab.ui.control.Label
        ResultadoEditField_2           matlab.ui.control.NumericEditField
        ResultadoEditField_2Label      matlab.ui.control.Label
        ToleranciaEditField_2          matlab.ui.control.NumericEditField
        ToleranciaEditField_2Label     matlab.ui.control.Label
        PuntoinicialEditField          matlab.ui.control.NumericEditField
        PuntoinicialEditFieldLabel     matlab.ui.control.Label
        fxEditField_2                  matlab.ui.control.EditField
        fxEditField_2Label             matlab.ui.control.Label
        GraficarButton_2               matlab.ui.control.Button
        CalcularButton_2               matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        ReglaFalsaTab                  matlab.ui.container.Tab
        ErrorEditField_2               matlab.ui.control.NumericEditField
        ErrorEditField_2Label          matlab.ui.control.Label
        ResultadoEditField_3           matlab.ui.control.NumericEditField
        ResultadoEditField_3Label      matlab.ui.control.Label
        ToleranciaEditField_3          matlab.ui.control.NumericEditField
        ToleranciaEditField_3Label     matlab.ui.control.Label
        Puntoinicial2EditField         matlab.ui.control.NumericEditField
        Puntoinicial2EditFieldLabel    matlab.ui.control.Label
        Puntoinicial1EditField         matlab.ui.control.NumericEditField
        Puntoinicial1EditFieldLabel    matlab.ui.control.Label
        fxEditField_3                  matlab.ui.control.EditField
        fxEditField_3Label             matlab.ui.control.Label
        GraficarButton_3               matlab.ui.control.Button
        CalcularButton_3               matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        SecanteTab                     matlab.ui.container.Tab
        ErrorEditField_3               matlab.ui.control.NumericEditField
        ErrorEditField_3Label          matlab.ui.control.Label
        ResultadoEditField_4           matlab.ui.control.NumericEditField
        ResultadoEditField_4Label      matlab.ui.control.Label
        ToleranciaEditField_4          matlab.ui.control.NumericEditField
        ToleranciaEditField_4Label     matlab.ui.control.Label
        Puntoinicial2EditField_2       matlab.ui.control.NumericEditField
        Puntoinicial2EditField_2Label  matlab.ui.control.Label
        Puntoinicial1EditField_2       matlab.ui.control.NumericEditField
        Puntoinicial1EditField_2Label  matlab.ui.control.Label
        fxEditField_4                  matlab.ui.control.EditField
        fxEditField_4Label             matlab.ui.control.Label
        GraficarButton_4               matlab.ui.control.Button
        CalcularButton_4               matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        RichmondTab                    matlab.ui.container.Tab
        ErrorEditField_4               matlab.ui.control.NumericEditField
        ErrorEditField_4Label          matlab.ui.control.Label
        ResultadoEditField_5           matlab.ui.control.NumericEditField
        ResultadoEditField_5Label      matlab.ui.control.Label
        ToleranciaEditField_5          matlab.ui.control.NumericEditField
        ToleranciaEditField_5Label     matlab.ui.control.Label
        PuntoinicialEditField_2        matlab.ui.control.NumericEditField
        PuntoinicialEditField_2Label   matlab.ui.control.Label
        fxEditField_5                  matlab.ui.control.EditField
        fxEditField_5Label             matlab.ui.control.Label
        GraficarButton_5               matlab.ui.control.Button
        CalcularButton_5               matlab.ui.control.Button
        UIAxes_5                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Bisección
        function c=biseccioneval(~,f,a,b,tol) 
            error=1;
            while error>tol
                c=(a+b)/2; %Punto medio
                fa=feval(f,a); %f(a)
                fc=feval(f,c); %f(c)
                if fa*fc<=0
                    b=c; % [a,c]
                    error=abs(c-a); % Calculando error
                else
                    a=c; % [c,b]
                    error=abs(b-c);
                end
            end
        end

        % Newton
        function [x1,err]=newtonwhile2(~,f,x0,tol)
            ff=sym(f); % Función anónima a simbólica
            df=diff(ff); % Derivada
            err=1;
            while err>tol
                fx0=f(x0); % f(x0)
                dfx0=double(subs(df,x0)); % f'(x0)
                x1=x0-fx0/dfx0;
                err=abs(x1-x0); % error absoluto
                x0=x1;
            end
        end

        % Proposición falsa
        function [xM,err]=metodo_posicion_falsa(~,f,xD,xL,tol)
            err=1;
            while err>tol
                fxD=f(xD); % f(x0)
                fxL=f(xL);
                xM=(xL*fxD-xD*fxL)/(fxD-fxL);
                err=abs(xD-xL); % error absoluto
                xL=xD;
                xD=xM;
            end
        end

        % Secante
        function [x2,err]=metodo_secante(~,f,x0,x1,tol)
            err=1;
            while err>tol
                fx0=f(x0); % f(x0)
                fx1=f(x1);
                x2=x1-(x1-x0)*fx0/(fx1-fx0);
                err=abs(x1-x0); % error absoluto
                x0=x1;
                x1=x2;
            end
        end

        % Richmond
        function [x1,err]=richmond(~,f,x0,tol)
            syms x
            f=sym(f); % Función anónima a simbólica
            df=diff(f,1);
            df2=diff(f,2);
            err=1;
            while err>tol
                f_x0=double(subs(f,x,x0));
                df_x0=double(subs(df,x,x0));
                df2_x0=double(subs(df2,x,x0));
                x1=x0-2*f_x0*df_x0/(2*df_x0^2-f_x0*df2_x0);
                err=abs(x1-x0); % error absoluto
                x0=x1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            f=app.fxEditField.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            a=app.aEditField.Value;
            b=app.bEditField.Value;
            tol=app.ToleranciaEditField.Value;
            c=app.biseccioneval(f,a,b,tol);
            app.ResultadoEditField.Value=c;
            fplot(app.UIAxes,f,[a,b],'b','LineWidth',1.5)
            hold(app.UIAxes,"on")
            scatter(app.UIAxes,c,0,'r*')
            cl=num2str(c);
            l2=strcat("(",cl," , 0)");
            fs=func2str(f);
            legend(app.UIAxes,fs(5:end),l2)
            hold(app.UIAxes,"off")
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            f=app.fxEditField.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            fplot(app.UIAxes,f,'g','LineWidth',1.5)
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            f=app.fxEditField_2.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            %editar
            tol=app.ToleranciaEditField_2.Value;
            x0=app.PuntoinicialEditField.Value;
            [x1,err]=app.newtonwhile2(f,x0,tol);
            app.ResultadoEditField_2.Value=x1;
            app.ErrorEditField.Value=err;
            fplot(app.UIAxes_2,f,[x1-1,x1+1],'b','LineWidth',1.5)
            hold(app.UIAxes_2,"on")
            scatter(app.UIAxes_2,x1,0,'r*')
            cl=num2str(x1);
            l2=strcat("(",cl," , 0)");
            fs=func2str(f);
            legend(app.UIAxes_2,fs(5:end),l2)
            hold(app.UIAxes_2,"off") 
        end

        % Button pushed function: GraficarButton_2
        function GraficarButton_2Pushed(app, event)
            f=app.fxEditField_2.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            f0=@(x)0*x;
            fplot(app.UIAxes_2,f,'g','LineWidth',1.5)
            hold(app.UIAxes_2,"on")
            fplot(app.UIAxes_2,f0,'r','LineWidth',1.5)
            hold(app.UIAxes_2,"off")
        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            f=app.fxEditField_3.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            %editar
            tol=app.ToleranciaEditField_3.Value;
            xD=app.Puntoinicial1EditField.Value;
            xL=app.Puntoinicial2EditField.Value;
            [xM,err]=app.metodo_posicion_falsa(f,xD,xL,tol);
            app.ResultadoEditField_3.Value=xM;
            app.ErrorEditField_2.Value=err;
            fplot(app.UIAxes_3,f,[xM-1,xM+1],'b','LineWidth',1.5)
            hold(app.UIAxes_3,"on")
            scatter(app.UIAxes_3,xM,0,'r*')
            cl=num2str(xM);
            l2=strcat("(",cl," , 0)");
            fs=func2str(f);
            legend(app.UIAxes_3,fs(5:end),l2)
            hold(app.UIAxes_3,"off")
        end

        % Button pushed function: GraficarButton_3
        function GraficarButton_3Pushed(app, event)
            f=app.fxEditField_3.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            f0=@(x)0*x;
            fplot(app.UIAxes_3,f,'g','LineWidth',1.5)
            hold(app.UIAxes_3,"on")
            fplot(app.UIAxes_3,f0,'r','LineWidth',1.5)
            hold(app.UIAxes_3,"off")
        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)
            f=app.fxEditField_4.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            %editar
            tol=app.ToleranciaEditField_4.Value;
            x0=app.Puntoinicial1EditField_2.Value;
            x1=app.Puntoinicial2EditField_2.Value;
            [x2,err]=app.metodo_secante(f,x0,x1,tol);
            app.ResultadoEditField_4.Value=x2;
            app.ErrorEditField_3.Value=err;
            fplot(app.UIAxes_4,f,[x2-1,x2+1],'b','LineWidth',1.5)
            hold(app.UIAxes_4,"on")
            scatter(app.UIAxes_4,x2,0,'r*')
            cl=num2str(x2);
            l2=strcat("(",cl," , 0)");
            fs=func2str(f);
            legend(app.UIAxes_4,fs(5:end),l2)
            hold(app.UIAxes_4,"off")
        end

        % Button pushed function: GraficarButton_4
        function GraficarButton_4Pushed(app, event)
            f=app.fxEditField_4.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            f0=@(x)0*x;
            fplot(app.UIAxes_4,f,'g','LineWidth',1.5)
            hold(app.UIAxes_4,"on")
            fplot(app.UIAxes_4,f0,'r','LineWidth',1.5)
            hold(app.UIAxes_4,"off")
        end

        % Value changed function: BackButton
        function BackButtonValueChanged(app, event)
        index
        delete(app)
        end

        % Button pushed function: CalcularButton_5
        function CalcularButton_5Pushed(app, event)
            f=app.fxEditField_5.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            %editar
            tol=app.ToleranciaEditField_5.Value;
            x0=app.PuntoinicialEditField_2.Value;
            [x1,err]=app.richmond(f,x0,tol);
            app.ResultadoEditField_5.Value=x1;
            app.ErrorEditField_4.Value=err;
            fplot(app.UIAxes_5,f,[x1-1,x1+1],'b','LineWidth',1.5)
            hold(app.UIAxes_5,"on")
            scatter(app.UIAxes_5,x1,0,'r*')
            cl=num2str(x1);
            l2=strcat("(",cl," , 0)");
            fs=func2str(f);
            legend(app.UIAxes_5,fs(5:end),l2)
            hold(app.UIAxes_5,"off") 
        end

        % Button pushed function: GraficarButton_5
        function GraficarButton_5Pushed(app, event)
            f=app.fxEditField_5.Value;
            f=strcat("@(x)",f);
            f=str2func(f);
            f0=@(x)0*x;
            fplot(app.UIAxes_5,f,'g','LineWidth',1.5)
            hold(app.UIAxes_5,"on")
            fplot(app.UIAxes_5,f0,'r','LineWidth',1.5)
            hold(app.UIAxes_5,"off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BiseccinTab = uitab(app.TabGroup);
            app.BiseccinTab.Title = 'Bisección';
            app.UIAxes = uiaxes(app.BiseccinTab);
            title(app.UIAxes, 'Gráfico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.fxEditFieldLabel = uilabel(app.BiseccinTab);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.BiseccinTab, 'text');
            app.aEditFieldLabel = uilabel(app.BiseccinTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.BiseccinTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.BiseccinTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.BiseccinTab, 'numeric');
            app.ToleranciaEditFieldLabel = uilabel(app.BiseccinTab);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.BiseccinTab, 'numeric');
            app.CalcularButton = uibutton(app.BiseccinTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultadoEditFieldLabel = uilabel(app.BiseccinTab);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.ResultadoEditField = uieditfield(app.BiseccinTab, 'numeric');
            app.GraficarButton = uibutton(app.BiseccinTab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.NewtonTab = uitab(app.TabGroup);
            app.NewtonTab.Title = 'Newton';
            app.UIAxes_2 = uiaxes(app.NewtonTab);
            title(app.UIAxes_2, 'Gráfico')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.CalcularButton_2 = uibutton(app.NewtonTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.GraficarButton_2 = uibutton(app.NewtonTab, 'push');
            app.GraficarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_2Pushed, true);
            app.GraficarButton_2.Text = 'Graficar';
            app.fxEditField_2Label = uilabel(app.NewtonTab);
            app.fxEditField_2Label.Text = 'f(x)';
            app.fxEditField_2 = uieditfield(app.NewtonTab, 'text');
            app.PuntoinicialEditFieldLabel = uilabel(app.NewtonTab);
            app.PuntoinicialEditFieldLabel.Text = 'Punto inicial';
            app.PuntoinicialEditField = uieditfield(app.NewtonTab, 'numeric');
            app.ToleranciaEditField_2Label = uilabel(app.NewtonTab);
            app.ToleranciaEditField_2Label.Text = 'Tolerancia';
            app.ToleranciaEditField_2 = uieditfield(app.NewtonTab, 'numeric');
            app.ResultadoEditField_2Label = uilabel(app.NewtonTab);
            app.ResultadoEditField_2Label.Text = 'Resultado';
            app.ResultadoEditField_2 = uieditfield(app.NewtonTab, 'numeric');
            app.ErrorEditFieldLabel = uilabel(app.NewtonTab);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.NewtonTab, 'numeric');
            app.ReglaFalsaTab = uitab(app.TabGroup);
            app.ReglaFalsaTab.Title = 'Regla Falsa';
            app.UIAxes_3 = uiaxes(app.ReglaFalsaTab);
            title(app.UIAxes_3, 'Gráfico')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.CalcularButton_3 = uibutton(app.ReglaFalsaTab, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.GraficarButton_3 = uibutton(app.ReglaFalsaTab, 'push');
            app.GraficarButton_3.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_3Pushed, true);
            app.GraficarButton_3.Text = 'Graficar';
            app.fxEditField_3Label = uilabel(app.ReglaFalsaTab);
            app.fxEditField_3Label.Text = 'f(x)';
            app.fxEditField_3 = uieditfield(app.ReglaFalsaTab, 'text');
            app.Puntoinicial1EditFieldLabel = uilabel(app.ReglaFalsaTab);
            app.Puntoinicial1EditFieldLabel.Text = 'Punto inicial 1';
            app.Puntoinicial1EditField = uieditfield(app.ReglaFalsaTab, 'numeric');
            app.Puntoinicial2EditFieldLabel = uilabel(app.ReglaFalsaTab);
            app.Puntoinicial2EditFieldLabel.Text = 'Punto inicial 2';
            app.Puntoinicial2EditField = uieditfield(app.ReglaFalsaTab, 'numeric');
            app.ToleranciaEditField_3Label = uilabel(app.ReglaFalsaTab);
            app.ToleranciaEditField_3Label.Text = 'Tolerancia';
            app.ToleranciaEditField_3 = uieditfield(app.ReglaFalsaTab, 'numeric');
            app.ResultadoEditField_3Label = uilabel(app.ReglaFalsaTab);
            app.ResultadoEditField_3Label.Text = 'Resultado';
            app.ResultadoEditField_3 = uieditfield(app.ReglaFalsaTab, 'numeric');
            app.ErrorEditField_2Label = uilabel(app.ReglaFalsaTab);
            app.ErrorEditField_2Label.Text = 'Error';
            app.ErrorEditField_2 = uieditfield(app.ReglaFalsaTab, 'numeric');
            app.SecanteTab = uitab(app.TabGroup);
            app.SecanteTab.Title = 'Secante';
            app.UIAxes_4 = uiaxes(app.SecanteTab);
            title(app.UIAxes_4, 'Gráfico')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.CalcularButton_4 = uibutton(app.SecanteTab, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.GraficarButton_4 = uibutton(app.SecanteTab, 'push');
            app.GraficarButton_4.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_4Pushed, true);
            app.GraficarButton_4.Text = 'Graficar';
            app.fxEditField_4Label = uilabel(app.SecanteTab);
            app.fxEditField_4Label.Text = 'f(x)';
            app.fxEditField_4 = uieditfield(app.SecanteTab, 'text');
            app.Puntoinicial1EditField_2Label = uilabel(app.SecanteTab);
            app.Puntoinicial1EditField_2Label.Text = 'Punto inicial 1';
            app.Puntoinicial1EditField_2 = uieditfield(app.SecanteTab, 'numeric');
            app.Puntoinicial2EditField_2Label = uilabel(app.SecanteTab);
            app.Puntoinicial2EditField_2Label.Text = 'Punto inicial 2';
            app.Puntoinicial2EditField_2 = uieditfield(app.SecanteTab, 'numeric');
            app.ToleranciaEditField_4Label = uilabel(app.SecanteTab);
            app.ToleranciaEditField_4Label.Text = 'Tolerancia';
            app.ToleranciaEditField_4 = uieditfield(app.SecanteTab, 'numeric');
            app.ResultadoEditField_4Label = uilabel(app.SecanteTab);
            app.ResultadoEditField_4Label.Text = 'Resultado';
            app.ResultadoEditField_4 = uieditfield(app.SecanteTab, 'numeric');
            app.ErrorEditField_3Label = uilabel(app.SecanteTab);
            app.ErrorEditField_3Label.Text = 'Error';
            app.ErrorEditField_3 = uieditfield(app.SecanteTab, 'numeric');
            app.RichmondTab = uitab(app.TabGroup);
            app.RichmondTab.Title = 'Richmond';
            app.UIAxes_5 = uiaxes(app.RichmondTab);
            title(app.UIAxes_5, 'Gráfico')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.CalcularButton_5 = uibutton(app.RichmondTab, 'push');
            app.CalcularButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_5Pushed, true);
            app.CalcularButton_5.Text = 'Calcular';
            app.GraficarButton_5 = uibutton(app.RichmondTab, 'push');
            app.GraficarButton_5.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_5Pushed, true);
            app.GraficarButton_5.Text = 'Graficar';
            app.fxEditField_5Label = uilabel(app.RichmondTab);
            app.fxEditField_5Label.Text = 'f(x)';
            app.fxEditField_5 = uieditfield(app.RichmondTab, 'text');
            app.PuntoinicialEditField_2Label = uilabel(app.RichmondTab);
            app.PuntoinicialEditField_2Label.Text = 'Punto inicial';
            app.PuntoinicialEditField_2 = uieditfield(app.RichmondTab, 'numeric');
            app.ToleranciaEditField_5Label = uilabel(app.RichmondTab);
            app.ToleranciaEditField_5Label.Text = 'Tolerancia';
            app.ToleranciaEditField_5 = uieditfield(app.RichmondTab, 'numeric');
            app.ResultadoEditField_5Label = uilabel(app.RichmondTab);
            app.ResultadoEditField_5Label.Text = 'Resultado';
            app.ResultadoEditField_5 = uieditfield(app.RichmondTab, 'numeric');
            app.ErrorEditField_4Label = uilabel(app.RichmondTab);
            app.ErrorEditField_4Label.Text = 'Error';
            app.ErrorEditField_4 = uieditfield(app.RichmondTab, 'numeric');
            app.BackButton = uibutton(app.UIFigure, 'state');
            app.BackButton.ValueChangedFcn = createCallbackFcn(app, @BackButtonValueChanged, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcuacionesNoLineales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end