classdef Interpolacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GradodelPolinomioSpinner       matlab.ui.control.Spinner
        GradodelPolinomioSpinnerLabel  matlab.ui.control.Label
        RespuestaEditField             matlab.ui.control.NumericEditField
        RespuestaEditFieldLabel        matlab.ui.control.Label
        PredictorEditField             matlab.ui.control.NumericEditField
        PredictorEditFieldLabel        matlab.ui.control.Label
        ClculoDropDown                 matlab.ui.control.DropDown
        ClculoDropDownLabel            matlab.ui.control.Label
        NmerodedatosSpinner            matlab.ui.control.Spinner
        NmerodedatosSpinnerLabel       matlab.ui.control.Label
        IncluirPredictorSwitch         matlab.ui.control.Switch
        IncluirPredictorSwitchLabel    matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        BackButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function [C,Px0]=MinimosCuadradosPolinimio(~,x,y,m,x0)
            n=length(x);
            F=zeros(n,m+1);
            for j=1:m+1
                F(:,j)=x'.^(m+1-j);
            end
            A=F'*F;
            b=F'*y';
            C=A\b;
            Px0=polyval(C',x0);
        end

        function C=MinimosCuadradosPolinimio2(~,x,y,m)
            n=length(x);
            F=zeros(n,m+1);
            for j=1:m+1
                F(:,j)=x'.^(m+1-j);
            end
            A=F'*F;
            b=F'*y';
            C=A\b;
        end

        %Lagrange con predictor
        function [Pa,P]=Lagrange(~,x,y,a)
            n=length(x)-1;
            syms t
            P=0;
            for i=1:n+1
                nu=1;
                de=1;
                for j=1:n+1
                    if j~=i
                        nu=nu*(t-x(j));
                        de=de*(x(i)-x(j));
                    end
                end
                P=P+y(i)*nu/de;
            end

            Pa=double(subs(P,a)); % P(a)

        end
        
        % Lagrange sin predictor
            function P=Lagrange2(~,x,y)
            n=length(x)-1;
            syms t
            P=0;
            for i=1:n+1
                nu=1;
                de=1;
                for j=1:n+1
                    if j~=i
                        nu=nu*(t-x(j));
                        de=de*(x(i)-x(j));
                    end
                end
                P=P+y(i)*nu/de;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PredictorEditField.Visible='off';
            app.PredictorEditFieldLabel.Visible='off';
            app.RespuestaEditField.Visible='off';
            app.RespuestaEditFieldLabel.Visible='off';
            app.GradodelPolinomioSpinner.Visible='off';
            app.GradodelPolinomioSpinnerLabel.Visible='off';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            index
            delete(app)
        end

        % Value changed function: IncluirPredictorSwitch
        function IncluirPredictorSwitchValueChanged(app, event)
            value = app.IncluirPredictorSwitch.Value;
            if value=="Off"
                app.PredictorEditField.Visible='off';
                app.PredictorEditFieldLabel.Visible='off';
                app.RespuestaEditField.Visible='off';
                app.RespuestaEditFieldLabel.Visible='off';
            elseif value=="On"
                app.PredictorEditField.Visible='on';
                app.PredictorEditFieldLabel.Visible='on';
                app.RespuestaEditField.Visible='on';
                app.RespuestaEditFieldLabel.Visible='on';
            end
            
        end

        % Value changed function: ClculoDropDown
        function ClculoDropDownValueChanged(app, event)
            value = app.ClculoDropDown.Value;
            if value=="Interpolacion"
                app.GradodelPolinomioSpinner.Visible='off';
                app.GradodelPolinomioSpinnerLabel.Visible='off';
            elseif value=="Ajuste"
                app.GradodelPolinomioSpinner.Visible='on';
                app.GradodelPolinomioSpinnerLabel.Visible='on';
            end
        end

        % Value changing function: NmerodedatosSpinner
        function NmerodedatosSpinnerValueChanging(app, event)
            M = event.Value;
            A=cell(M,2);
            app.UITable.Data=A;
            app.UITable.ColumnEditable=true;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            a=app.PredictorEditField.Value;
            A=app.UITable.Data;
            A=str2double(A);
            x=A(:,1);
            y=A(:,2);

            Metodo=app.ClculoDropDown.Value;
            incpred = app.IncluirPredictorSwitch.Value;
            if Metodo=="Interpolacion"
                if incpred=="On"
                    [Pa,P]=app.Lagrange(x,y,a);
                    % Graficando la interpolación, los puntos dados y el punto encontrado
                    fplot(app.UIAxes,P, [min(x)-1, max(x)+1], 'LineWidth', 1.5); % Gráfico de la interpolación
                    hold(app.UIAxes,"on")
                    scatter(app.UIAxes,x, y, 'r*'); % Puntos dados en rojo
                    scatter(app.UIAxes,a, Pa, 'go', 'filled'); % Punto encontrado en verde
                    legend(app.UIAxes,'Interpolación', 'Puntos Dados', 'Punto Encontrado','Location','best');
                    hold(app.UIAxes,"off")
                    app.RespuestaEditField.Value=Pa;
                elseif incpred=="Off"
                    P=app.Lagrange2(x,y);
                    % Graficando la interpolación, los puntos dados y el punto encontrado
                    fplot(app.UIAxes,P, [min(x)-1, max(x)+1], 'LineWidth', 1.5); % Gráfico de la interpolación
                    hold(app.UIAxes,"on")
                    scatter(app.UIAxes,x, y, 'r*'); % Puntos dados en rojo
                    legend(app.UIAxes,'Interpolación', 'Puntos Dados','Location','best');
                    hold(app.UIAxes,"off")
                end
            elseif Metodo=="Ajuste"
                m=app.GradodelPolinomioSpinner.Value;
                x=x';
                y=y';
                x0=a;
                if incpred=="On"
                    [C,Px0]=app.MinimosCuadradosPolinimio(x,y,m,x0);
                    % Graficando la interpolación, los puntos dados y el punto encontrado
                    D=min(x)-1:0.01:max(x)+1;
                    PD=polyval(C',D);
                    plot(app.UIAxes,x,y,'r*')
                    hold(app.UIAxes,"on")
                    plot(app.UIAxes,D,PD,'b','LineWidth',0.1)
                    scatter(app.UIAxes,x0,Px0,'go','filled')
                    legend(app.UIAxes,'Ajuste', 'Puntos Dados','Punto encontrado','Location','best');
                    hold(app.UIAxes,"off")
                    app.RespuestaEditField.Value=Px0;
                elseif incpred=="Off"
                    C=app.MinimosCuadradosPolinimio2(x,y,m);
                    % Graficando la interpolación, los puntos dados y el punto encontrado
                    D=min(x)-1:0.01:max(x)+1;
                    PD=polyval(C',D);
                    plot(app.UIAxes,x,y,'r*')
                    hold(app.UIAxes,"on")
                    plot(app.UIAxes,D,PD,'b','LineWidth',0.1)
                    legend(app.UIAxes,'Ajuste', 'Puntos Dados','Location','best');
                    hold(app.UIAxes,"off")
                end
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfico')
            xlabel(app.UIAxes, 'Predictores')
            ylabel(app.UIAxes, 'Respuestas')
            zlabel(app.UIAxes, 'Z')
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Predictores'; 'Respuestas'};
            app.UITable.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.IncluirPredictorSwitchLabel = uilabel(app.UIFigure);
            app.IncluirPredictorSwitchLabel.Text = 'Incluir Predictor';
            app.IncluirPredictorSwitch = uiswitch(app.UIFigure, 'slider');
            app.IncluirPredictorSwitch.ValueChangedFcn = createCallbackFcn(app, @IncluirPredictorSwitchValueChanged, true);
            app.NmerodedatosSpinnerLabel = uilabel(app.UIFigure);
            app.NmerodedatosSpinnerLabel.Text = 'Número de datos';
            app.NmerodedatosSpinner = uispinner(app.UIFigure);
            app.NmerodedatosSpinner.ValueChangingFcn = createCallbackFcn(app, @NmerodedatosSpinnerValueChanging, true);
            app.NmerodedatosSpinner.Limits = [1 Inf];
            app.NmerodedatosSpinner.Value = 1;
            app.ClculoDropDownLabel = uilabel(app.UIFigure);
            app.ClculoDropDownLabel.Text = 'Cálculo';
            app.ClculoDropDown = uidropdown(app.UIFigure);
            app.ClculoDropDown.Items = {'Interpolacion', 'Ajuste'};
            app.ClculoDropDown.ValueChangedFcn = createCallbackFcn(app, @ClculoDropDownValueChanged, true);
            app.ClculoDropDown.Value = 'Interpolacion';
            app.PredictorEditFieldLabel = uilabel(app.UIFigure);
            app.PredictorEditFieldLabel.Text = 'Predictor';
            app.PredictorEditField = uieditfield(app.UIFigure, 'numeric');
            app.RespuestaEditFieldLabel = uilabel(app.UIFigure);
            app.RespuestaEditFieldLabel.Text = 'Respuesta';
            app.RespuestaEditField = uieditfield(app.UIFigure, 'numeric');
            app.GradodelPolinomioSpinnerLabel = uilabel(app.UIFigure);
            app.GradodelPolinomioSpinnerLabel.Text = 'Grado del Polinomio';
            app.GradodelPolinomioSpinner = uispinner(app.UIFigure);
            app.GradodelPolinomioSpinner.Limits = [1 Inf];
            app.GradodelPolinomioSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interpolacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end