classdef SistemasDeEcuacionesLineales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PuntoInicialEditField         matlab.ui.control.EditField
        PuntoInicialEditFieldLabel    matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        TamaodeMatrizEditField_2      matlab.ui.control.NumericEditField
        XLabel                        matlab.ui.control.Label
        NumerodeIteracionesEditField  matlab.ui.control.NumericEditField
        NumerodeIteracionesEditFieldLabel  matlab.ui.control.Label
        ToleranciaEditField           matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel      matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        PlayButton                    matlab.ui.control.Button
        MtodoDropDown                 matlab.ui.control.DropDown
        MtodoDropDownLabel            matlab.ui.control.Label
        TamaodeMatrizEditField        matlab.ui.control.NumericEditField
        TamaodeMatrizEditFieldLabel   matlab.ui.control.Label
        TerminosButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        % Metodo Inversa
        function X=Inversa(~,A,b)
            [m,n]=size(A);
            if m==n
                M=det(A);
                if M~=0
                    X=A\b;
                else
                    errordlg('El determinante de la matríz debe ser diferente de cero','Error','modal');
                    X=zeros(m,n+1);
                end 
            else
            errordlg('El algoritmo no aplica para matrices no cuadradas','Error','modal');
            X=zeros(m,n+1);
            end
        end
        
        % Metodo Cramer
        function X = Cramer(~,A,b)
            [m,n]=size(A);
            if m==n
            M=det(A);
                if M~=0
                    X=zeros(n,1);
                    for j=1:n
                        B=A;
                        B(:,j)=b;
                        X(j)=det(B)/det(A);
                    end
                else
                    errordlg('El determinante de la matríz debe ser diferente de cero','Error','modal');
                    X=zeros(m,n+1);
                end
            else
                errordlg('El algoritmo no aplica para matrices no cuadradas','Error','modal');
                X=zeros(m,n+1);
            end
        end

        function X= GaussSeidel(~,A,b,x0,tol,m)

            n=length(b);
            x0=x0';
            for k=1:m
                X=zeros(n,1);
                for i=1:n
                    X(i)=(b(i)-A(i,1:i-1)*X(1:i-1)-A(i,i+1:n)*x0(i+1:n))/A(i,i);
                end
                err=norm(X-x0);
                if err<tol
                    break
                else
                    x0=X;
                end
            end
            if err>tol
                warndlg('El error no está dentro de la tolerancia indicada, pueden necesitarse más iteraciones','Warning','modal')
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumerodeIteracionesEditField.Visible='off';
            app.NumerodeIteracionesEditFieldLabel.Visible='off';
            app.ToleranciaEditField.Visible='off';
            app.ToleranciaEditFieldLabel.Visible='off';
            app.PuntoInicialEditField.Visible='off';
            app.PuntoInicialEditFieldLabel.Visible='off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            Data={"","","",""};
            app.UITable.Data=Data;
            
        end

        % Button pushed function: TerminosButton
        function TerminosButtonPushed(app, event)
            M=app.TamaodeMatrizEditField.Value;
            N=app.TamaodeMatrizEditField_2.Value;
            A=cell(M,N);
            app.UITable.Data=A;
            B=cell(M,1);
            app.UITable2.Data=B;

            NombreColumnas=cell(1,M);

            for i=1:N
                NombreColumnas{i}=sprintf('X%d',i);
            end 

            app.UITable.ColumnName=NombreColumnas;


            app.UITable.ColumnEditable=true;
            app.UITable2.ColumnEditable=true;

           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            A=app.UITable.Data;
            A=str2double(A);
            b=app.UITable2.Data;
            b=str2double(b);
            tol = app.ToleranciaEditField.Value;
            m = app.NumerodeIteracionesEditField.Value;
            x0=app.PuntoInicialEditField.Value;
            x0=str2num(x0);


            Metodo=app.MtodoDropDown.Value;
            if Metodo=="Cramer"
                X=app.Cramer(A,b);
            elseif Metodo=="Inversa"
                X=app.Inversa(A,b);
            elseif Metodo=="Gauss Seidel"
                X=app.GaussSeidel(A,b,x0,tol,m);
            end 
            X=num2cell(X);
            app.UITable2.Data=X;
            N=app.TamaodeMatrizEditField.Value;
            v=eye(N);
            v=num2cell(v);
            app.UITable.Data=v;

        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            Metodo = app.MtodoDropDown.Value;
            if Metodo=="Gauss Seidel"
            app.NumerodeIteracionesEditField.Visible='on';
            app.NumerodeIteracionesEditFieldLabel.Visible='on';
            app.ToleranciaEditField.Visible='on';
            app.ToleranciaEditFieldLabel.Visible='on';
            app.PuntoInicialEditField.Visible='on';
            app.PuntoInicialEditFieldLabel.Visible='on';
            else
            app.NumerodeIteracionesEditField.Visible='off';
            app.NumerodeIteracionesEditFieldLabel.Visible='off';
            app.ToleranciaEditField.Visible='off';
            app.ToleranciaEditFieldLabel.Visible='off';
            app.PuntoInicialEditField.Visible='off';
            app.PuntoInicialEditFieldLabel.Visible='off';  
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            index
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X1'; 'X2'; 'X3'; 'X4'};
            app.UITable.RowName = {};
            app.TerminosButton = uibutton(app.UIFigure, 'push');
            app.TerminosButton.ButtonPushedFcn = createCallbackFcn(app, @TerminosButtonPushed, true);
            app.TerminosButton.Text = 'Terminos';
            app.TamaodeMatrizEditFieldLabel = uilabel(app.UIFigure);
            app.TamaodeMatrizEditFieldLabel.Text = 'Tamaño de Matriz';
            app.TamaodeMatrizEditField = uieditfield(app.UIFigure, 'numeric');
            app.TamaodeMatrizEditField.Limits = [1 Inf];
            app.TamaodeMatrizEditField.Value = 4;
            app.MtodoDropDownLabel = uilabel(app.UIFigure);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.UIFigure);
            app.MtodoDropDown.Items = {'Inversa', 'Cramer', 'Gauss Seidel', 'Gauss Jordan', 'Descomposición Gaussiana', 'Thomas', 'Jacobi'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'Inversa';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Y'};
            app.UITable2.RowName = {};
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumerodeIteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodeIteracionesEditFieldLabel.Text = 'Numero de Iteraciones';
            app.NumerodeIteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.TamaodeMatrizEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TamaodeMatrizEditField_2.Limits = [1 Inf];
            app.TamaodeMatrizEditField_2.Value = 4;
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.PuntoInicialEditFieldLabel = uilabel(app.UIFigure);
            app.PuntoInicialEditFieldLabel.Text = 'Punto Inicial';
            app.PuntoInicialEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SistemasDeEcuacionesLineales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end