classdef SistemasEcuacionesNoLineales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToleranciaEditField             matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel        matlab.ui.control.Label
        MtodoDropDown                   matlab.ui.control.DropDown
        MtodoDropDownLabel              matlab.ui.control.Label
        NmerodeIteracionesSpinner       matlab.ui.control.Spinner
        NmerodeIteracionesSpinnerLabel  matlab.ui.control.Label
        EcuacionesSpinner               matlab.ui.control.Spinner
        EcuacionesSpinnerLabel          matlab.ui.control.Label
        PuntoInicialEditField           matlab.ui.control.EditField
        PuntoInicialEditFieldLabel      matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        BackButton                      matlab.ui.control.Button
    end

    
    methods (Access = private)
        function [X1,err]=newton_multivariable(~,F,X0,tol,m)
            X=symvar(F);
            j=jacobian(F,X); % Se construye la matriz jacobiana
            for k=1:m
                jX0=double(subs(j,X,X0));
                FX0=double(subs(F,X,X0));
                X1=X0'-jX0\FX0;
                err=norm(X1-X0');
                if err<tol
                    break;
                else
                    X0=X1';
                end
            end
            if err>tol
                warndlg('El error no está dentro de la tolerancia indicada, pueden necesitarse más iteraciones','Warning','modal')
            end
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            index
            delete(app)
        end

        % Value changing function: EcuacionesSpinner
        function EcuacionesSpinnerValueChanging(app, event)
            M = event.Value;
            A=cell(M,1);
            app.UITable.Data=A;
            B=cell(M,1);
            app.UITable2.Data=B;
            app.UITable.ColumnEditable=true;
            app.UITable2.ColumnEditable=false;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            M = app.EcuacionesSpinner.Value;
            syms x [1 M]
            F=app.UITable.Data;
            F=str2sym(F);
            x0=app.PuntoInicialEditField.Value;
            x0=str2num(x0);
            tol=app.ToleranciaEditField.Value;
            m=app.NmerodeIteracionesSpinner.Value;

            Metodo=app.MtodoDropDown.Value;
            if Metodo=="Newton Multivariable"
                x1=app.newton_multivariable(F,x0,tol,m);
                  
            elseif Metodo=="Vpasolve"
                x1=vpasolve(F,x0);
                x1=struct2table(x1);
                x1=table2array(x1);
                x1=double(x1);
                x1=x1';
            end 
            app.UITable2.Data=x1;
        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            Metodo = app.MtodoDropDown.Value;
            if Metodo=="Newton Multivariable"
            app.NmerodeIteracionesSpinner.Visible='on';
            app.NmerodeIteracionesSpinnerLabel.Visible='on';
            app.ToleranciaEditField.Visible='on';
            app.ToleranciaEditFieldLabel.Visible='on';
            else
            app.NmerodeIteracionesSpinner.Visible='off';
            app.NmerodeIteracionesSpinnerLabel.Visible='off';
            app.ToleranciaEditField.Visible='off';
            app.ToleranciaEditFieldLabel.Visible='off'; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Ecuaciones'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Resultados'};
            app.UITable2.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PuntoInicialEditFieldLabel = uilabel(app.UIFigure);
            app.PuntoInicialEditFieldLabel.Text = 'Punto Inicial';
            app.PuntoInicialEditField = uieditfield(app.UIFigure, 'text');
            app.EcuacionesSpinnerLabel = uilabel(app.UIFigure);
            app.EcuacionesSpinnerLabel.Text = 'Ecuaciones';
            app.EcuacionesSpinner = uispinner(app.UIFigure);
            app.EcuacionesSpinner.ValueChangingFcn = createCallbackFcn(app, @EcuacionesSpinnerValueChanging, true);
            app.EcuacionesSpinner.Limits = [0 Inf];
            app.NmerodeIteracionesSpinnerLabel = uilabel(app.UIFigure);
            app.NmerodeIteracionesSpinnerLabel.Text = 'Número de Iteraciones';
            app.NmerodeIteracionesSpinner = uispinner(app.UIFigure);
            app.NmerodeIteracionesSpinner.Limits = [1 Inf];
            app.NmerodeIteracionesSpinner.Value = 1;
            app.MtodoDropDownLabel = uilabel(app.UIFigure);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.UIFigure);
            app.MtodoDropDown.Items = {'Newton Multivariable', 'Vpasolve'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'Newton Multivariable';
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SistemasEcuacionesNoLineales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end